classdef AnotacionesFlexibles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIAnFlexibles          matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        textbfBienvenidoaunderlineVentanadeAnotacionesFlexiblesLabel  matlab.ui.control.Label
        ConfiguracincanalesparamontajeyanotacionesPanel  matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        Label_2                matlab.ui.control.Label
        SeleccionlistaButton   matlab.ui.control.Button
        CanalT5DropDown        matlab.ui.control.DropDown
        CanalT5DropDownLabel   matlab.ui.control.Label
        CanalO2DropDown        matlab.ui.control.DropDown
        CanalO2DropDownLabel   matlab.ui.control.Label
        CanalC3DropDown        matlab.ui.control.DropDown
        CanalC3DropDownLabel   matlab.ui.control.Label
        CanalA2DropDown        matlab.ui.control.DropDown
        CanalA2DropDownLabel   matlab.ui.control.Label
        CanalT4DropDown        matlab.ui.control.DropDown
        CanalT4DropDownLabel   matlab.ui.control.Label
        CanalO1DropDown        matlab.ui.control.DropDown
        CanalO1DropDownLabel   matlab.ui.control.Label
        CanalF4DropDown        matlab.ui.control.DropDown
        CanalF4DropDownLabel   matlab.ui.control.Label
        CanalA1DropDown        matlab.ui.control.DropDown
        CanalA1DropDownLabel   matlab.ui.control.Label
        CanalT3DropDown        matlab.ui.control.DropDown
        CanalT3DropDownLabel   matlab.ui.control.Label
        CanalP4DropDown        matlab.ui.control.DropDown
        CanalP4DropDownLabel   matlab.ui.control.Label
        CanalF3DropDown        matlab.ui.control.DropDown
        CanalF3DropDownLabel   matlab.ui.control.Label
        CanalCZDropDown        matlab.ui.control.DropDown
        CanalCZDropDownLabel   matlab.ui.control.Label
        CanalF8DropDown        matlab.ui.control.DropDown
        CanalF8DropDownLabel   matlab.ui.control.Label
        CanalP3DropDown        matlab.ui.control.DropDown
        CanalP3DropDownLabel   matlab.ui.control.Label
        CanalFP2DropDown       matlab.ui.control.DropDown
        CanalFP2DropDownLabel  matlab.ui.control.Label
        CanalT6DropDown        matlab.ui.control.DropDown
        CanalT6DropDownLabel   matlab.ui.control.Label
        CanalF7DropDown        matlab.ui.control.DropDown
        CanalF7DropDownLabel   matlab.ui.control.Label
        CanalC4DropDown        matlab.ui.control.DropDown
        CanalC4DropDownLabel   matlab.ui.control.Label
        CanalFP1DropDown       matlab.ui.control.DropDown
        CanalFP1Label          matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        FileSelector           wt.FileSelector
    end

    % Código realizado por Javier Pérez 2024
    properties (Access = private)
        MainApp % Handle de la app principal
        DBhndl % Conexión con base de datos
        hdr % Header estudio eeg
        record % Señales estudio eeg
        dropDowns % Handles dropdowns selección de canales
    end
    
    properties (Access = public)
        EEGAnFlexibles % EEG leído para anotaciones
        LBLSAnFlexibles % Etiquetas EEG leído para anotaciones
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, DBcon)
            app.MainApp = mainApp; % Se guarda handle de mainApp
            app.DBhndl = DBcon; % Se guarda conexión con base de datos
        end

        % Close request function: UIAnFlexibles
        function UIAnFlexiblesCloseRequest(app, event)
            
            % En caso de cerrarse la ventana, se habilita nuevamente su
            % botón de lanzamiento
            if isvalid(app.MainApp)
                app.MainApp.AnotacionesFlexButton.Enable = "on";
            end
         

            % Se cierra ventana
            delete(app)
        end

        % Callback function: FileSelector
        function FileSelectorValueChanged(app, event)
            path_eeg = app.FileSelector.Value; % Se obtiene el path que el usuario seleccionó

            if isfile(path_eeg) && endsWith(path_eeg,'.edf', 'IgnoreCase', true)
                [app.hdr, app.record] = openedf(path_eeg); % Lectura del EEG

                % Se obtienen los nombres de los canales disponibles más la
                % opción N/A
                nombresCanales = [app.hdr.SignalLabels; "No aplica"];

                % Lista de todos los dropdown para selección de canales
                app.dropDowns = {app.CanalT5DropDown, app.CanalO2DropDown, app.CanalC3DropDown, ...
                                 app.CanalA2DropDown, app.CanalT4DropDown, app.CanalO1DropDown, ...
                                 app.CanalF4DropDown, app.CanalA1DropDown, app.CanalT3DropDown, ...
                                 app.CanalP4DropDown, app.CanalF3DropDown, app.CanalCZDropDown, ...
                                 app.CanalF8DropDown, app.CanalP3DropDown, app.CanalFP2DropDown, ...
                                 app.CanalT6DropDown, app.CanalF7DropDown, app.CanalC4DropDown, ...
                                 app.CanalFP1DropDown};

                % Nombres de referencia para cada DropDown
                defChannels = {"T5", "O2", "C3", "A2",  "T4", "O1", "F4", ...
                               "A1", "T3", "P4", "F3", "CZ",  "F8", "P3", ...
                               "FP2", "T6", "F7", "C4", "FP1"};

                for i = 1:numel(app.dropDowns)
                    % Se asignan las opciones de los canales al dropdown actual
                    app.dropDowns{i}.Items = nombresCanales;

                    % Se busca coincidencia parcial en los nombres de los canales
                    matchIndex = find(contains(lower(nombresCanales), lower(defChannels{i})), 1);

                    % Si hay una coincidencia, se establece como valor predeterminado
                    if ~isempty(matchIndex)
                        app.dropDowns{i}.Value = nombresCanales{matchIndex};
                    else
                        % Si no hay coincidencia, se establece "No aplica" como valor predeterminado
                        app.dropDowns{i}.Value = "No aplica";
                    end
                end

                % Se hace visible el panel de configuraciones para montaje
                app.ConfiguracincanalesparamontajeyanotacionesPanel.Visible = "on";
                
                % Se habilita botón de confirmación
            app.SeleccionlistaButton.Enable = "on";
            else
                % Alerta al usuario
                uialert(app.UIAnFlexibles, 'La ruta proporcionada no corresponde a un archivo .edf válido', 'Error de archivo', 'Icon', 'error');
                app.FileSelector.Value = "";
            end
            
        end

        % Button pushed function: SeleccionlistaButton
        function SeleccionlistaButtonPushed(app, event)
            % Se inhabilita botón de confirmación
            app.SeleccionlistaButton.Enable = "off";
            
            % Se inicializa un arreglo de celdas para almacenar los valores seleccionados
            chnlsSeleccionados = cell(1, numel(app.dropDowns));

            % Se recorre cada dropdown y obtener el valor seleccionado
            for i = 1:numel(app.dropDowns)
                chnlsSeleccionados{i} = app.dropDowns{i}.Value;
            end
            disp(chnlsSeleccionados)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIAnFlexibles = uifigure('Visible', 'off');
            app.UIAnFlexibles.Name = 'MATLAB App';
            app.UIAnFlexibles.CloseRequestFcn = createCallbackFcn(app, @UIAnFlexiblesCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIAnFlexibles);
            app.FileSelector = wt.FileSelector(app.GridLayout);
            app.FileSelector.Filter = "*.edf";
            app.FileSelector.ValueChangedFcn = createCallbackFcn(app, @FileSelectorValueChanged, true);
            app.FileSelector.Tooltip = "Presione el ícono de carpeta para abrir el explorador de archivos";
            app.Label = uilabel(app.GridLayout);
            app.Label.Interpreter = 'html';
            app.Label.Text = 'Seleccione el estudio a analizar <b><font color="red">(.edf)</font></b>:';
            app.ConfiguracincanalesparamontajeyanotacionesPanel = uipanel(app.GridLayout);
            app.ConfiguracincanalesparamontajeyanotacionesPanel.Title = 'Configuración canales para montaje y anotaciones';
            app.ConfiguracincanalesparamontajeyanotacionesPanel.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.ConfiguracincanalesparamontajeyanotacionesPanel);
            app.CanalFP1Label = uilabel(app.GridLayout2);
            app.CanalFP1Label.Text = 'Canal  FP1:';
            app.CanalFP1DropDown = uidropdown(app.GridLayout2);
            app.CanalC4DropDownLabel = uilabel(app.GridLayout2);
            app.CanalC4DropDownLabel.Text = 'Canal  C4:';
            app.CanalC4DropDown = uidropdown(app.GridLayout2);
            app.CanalF7DropDownLabel = uilabel(app.GridLayout2);
            app.CanalF7DropDownLabel.Text = 'Canal  F7:';
            app.CanalF7DropDown = uidropdown(app.GridLayout2);
            app.CanalT6DropDownLabel = uilabel(app.GridLayout2);
            app.CanalT6DropDownLabel.Text = 'Canal  T6:';
            app.CanalT6DropDown = uidropdown(app.GridLayout2);
            app.CanalFP2DropDownLabel = uilabel(app.GridLayout2);
            app.CanalFP2DropDownLabel.Text = 'Canal  FP2:';
            app.CanalFP2DropDown = uidropdown(app.GridLayout2);
            app.CanalP3DropDownLabel = uilabel(app.GridLayout2);
            app.CanalP3DropDownLabel.Text = 'Canal  P3:';
            app.CanalP3DropDown = uidropdown(app.GridLayout2);
            app.CanalF8DropDownLabel = uilabel(app.GridLayout2);
            app.CanalF8DropDownLabel.Text = 'Canal  F8:';
            app.CanalF8DropDown = uidropdown(app.GridLayout2);
            app.CanalCZDropDownLabel = uilabel(app.GridLayout2);
            app.CanalCZDropDownLabel.Text = 'Canal  CZ:';
            app.CanalCZDropDown = uidropdown(app.GridLayout2);
            app.CanalF3DropDownLabel = uilabel(app.GridLayout2);
            app.CanalF3DropDownLabel.Text = 'Canal  F3:';
            app.CanalF3DropDown = uidropdown(app.GridLayout2);
            app.CanalP4DropDownLabel = uilabel(app.GridLayout2);
            app.CanalP4DropDownLabel.Text = 'Canal  P4:';
            app.CanalP4DropDown = uidropdown(app.GridLayout2);
            app.CanalT3DropDownLabel = uilabel(app.GridLayout2);
            app.CanalT3DropDownLabel.Text = 'Canal  T3:';
            app.CanalT3DropDown = uidropdown(app.GridLayout2);
            app.CanalA1DropDownLabel = uilabel(app.GridLayout2);
            app.CanalA1DropDownLabel.Text = 'Canal  A1:';
            app.CanalA1DropDown = uidropdown(app.GridLayout2);
            app.CanalF4DropDownLabel = uilabel(app.GridLayout2);
            app.CanalF4DropDownLabel.Text = 'Canal  F4:';
            app.CanalF4DropDown = uidropdown(app.GridLayout2);
            app.CanalO1DropDownLabel = uilabel(app.GridLayout2);
            app.CanalO1DropDownLabel.Text = 'Canal  O1:';
            app.CanalO1DropDown = uidropdown(app.GridLayout2);
            app.CanalT4DropDownLabel = uilabel(app.GridLayout2);
            app.CanalT4DropDownLabel.Text = 'Canal  T4:';
            app.CanalT4DropDown = uidropdown(app.GridLayout2);
            app.CanalA2DropDownLabel = uilabel(app.GridLayout2);
            app.CanalA2DropDownLabel.Text = 'Canal  A2:';
            app.CanalA2DropDown = uidropdown(app.GridLayout2);
            app.CanalC3DropDownLabel = uilabel(app.GridLayout2);
            app.CanalC3DropDownLabel.Text = 'Canal  C3:';
            app.CanalC3DropDown = uidropdown(app.GridLayout2);
            app.CanalO2DropDownLabel = uilabel(app.GridLayout2);
            app.CanalO2DropDownLabel.Text = 'Canal  O2:';
            app.CanalO2DropDown = uidropdown(app.GridLayout2);
            app.CanalT5DropDownLabel = uilabel(app.GridLayout2);
            app.CanalT5DropDownLabel.Text = 'Canal  T5:';
            app.CanalT5DropDown = uidropdown(app.GridLayout2);
            app.SeleccionlistaButton = uibutton(app.GridLayout2, 'push');
            app.SeleccionlistaButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionlistaButtonPushed, true);
            app.SeleccionlistaButton.Enable = 'off';
            app.SeleccionlistaButton.Text = 'Selección lista';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = 'Por favor, indique y verifique que cada canal del estudio sea asignado correctamente debajo. Una vez finalice la revisión, presione el botón en la esquina inferior derecha.';
            app.textbfBienvenidoaunderlineVentanadeAnotacionesFlexiblesLabel = uilabel(app.GridLayout);
            app.textbfBienvenidoaunderlineVentanadeAnotacionesFlexiblesLabel.Interpreter = 'latex';
            app.textbfBienvenidoaunderlineVentanadeAnotacionesFlexiblesLabel.Text = {'\textbf{¡Bienvenido/a!}'; '\underline{Ventana de Anotaciones Flexibles}'};
            % Show the figure after all components are created
            app.UIAnFlexibles.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnotacionesFlexibles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIAnFlexibles)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIAnFlexibles)
        end
    end
end