classdef procesamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Carac                     matlab.ui.Figure
        CagarArchivoMATButton     matlab.ui.control.Button
        RegresarButton            matlab.ui.control.Button
        SeleccionarEEGdeBasededatosButton  matlab.ui.control.Button
        EditField_2               matlab.ui.control.NumericEditField
        sLabel                    matlab.ui.control.Label
        CalcularButton            matlab.ui.control.Button
        CaractersticasTpicasButtonGroup  matlab.ui.container.ButtonGroup
        CurtosisButton            matlab.ui.control.RadioButton
        DesviacinestndarButton    matlab.ui.control.RadioButton
        FrecuenciaMxButton        matlab.ui.control.RadioButton
        CrucesporCeroButton       matlab.ui.control.RadioButton
        MAVButton                 matlab.ui.control.RadioButton
        MediaButton               matlab.ui.control.RadioButton
        MuestrasporventanaLabel   matlab.ui.control.Label
        ExtraerVectordeCaractersticasButton  matlab.ui.control.Button
        TiempoMaxsEditField       matlab.ui.control.NumericEditField
        TiempoMaxsEditFieldLabel  matlab.ui.control.Label
        TiempoMinsEditField       matlab.ui.control.NumericEditField
        TiempoMinsEditFieldLabel  matlab.ui.control.Label
        VisualizarButton          matlab.ui.control.Button
        CanalSpinner              matlab.ui.control.Spinner
        CanalSpinnerLabel         matlab.ui.control.Label
        CagarNuevoEEGButton       matlab.ui.control.Button
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Minvalue % Description
        Maxvalue % Description
        tiempo
        datos
        indexsup
        indexinf
        header
        freq
        Matriz_features
        channel_ventana
        c
        op
    end
    
    properties (Access = private)
        DialogApp % Description
        PruebaApp
        Conexion
        CallingApp
    end
    
    
    methods (Access = public)
      function features_data(app,data,hdr)
            app.datos = data;
            app.header = hdr;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con)
            movegui(app.Carac,"center");
            features_data(app,app.datos,app.header);
            app.ExtraerVectordeCaractersticasButton.Enable = 'off';
            app.CalcularButton.Enable = 'off';
            app.VisualizarButton.Enable = 'off';
            app.CallingApp = mainapp;
            app.Conexion = con;
            if (isempty(app.Conexion)||~isopen(app.Conexion))
                app.SeleccionarEEGdeBasededatosButton.Enable = 'off';
            else
                app.SeleccionarEEGdeBasededatosButton.Enable = 'on';
            end
        end

        % Button pushed function: CagarNuevoEEGButton
        function CagarNuevoEEGButtonPushed(app, event)
            [file,path,~] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            [hdr, record] = edfread(strcat(path,file));
            app.datos = record;
            app.header = hdr;
            app.freq = hdr.frequency(1);
            app.VisualizarButton.Enable = 'on';
            app.ExtraerVectordeCaractersticasButton.Enable ='on';
        end

        % Button pushed function: VisualizarButton
        function VisualizarButtonPushed(app, event)

            value = app.CanalSpinner.Value;
            record = app.datos;
            app.tiempo = 1:length(record);
           
            tmin = 256*(app.TiempoMinsEditField.Value);
            tmax = 256*(app.TiempoMaxsEditField.Value);
            if tmax>length(record)
                tmax = length(record);
                app.TiempoMaxsEditField.Value = round(length(record)/256);
            elseif mod(tmax,1)~=0 || mod(tmin,1)~=0 %si es decimal
                tmax = round(tmax);
                tmin = round(tmin);
            end
            record = cell2mat(record); %Se convierte a numeros para graficar
            app.UIAxes.XLim=[tmin, tmax];
            app.indexinf = tmin;
            app.indexsup = tmax;
            switch value
                case 1
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 2
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 3
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 4
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 5
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 6
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 7
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 8
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 9
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 10
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 11
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 12
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 13
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 14
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 15
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 16
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 17
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 18
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 19
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 20
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 21
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 22
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 23
                    plot(app.UIAxes,app.tiempo,record(value,:))
            end
            app.CalcularButton.Enable = 'on';
        end

        % Button pushed function: ExtraerVectordeCaractersticasButton
        function ExtraerVectordeCaractersticasButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            %app.ExtraerVectordeCaractersticasButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = features(app);
        end

        % Close request function: Carac
        function CaracCloseRequest(app, event)
        if (isempty(app.Conexion)||~isopen(app.Conexion))
            app.CallingApp.AgregarnuevopacienteButton.Enable = 'off';
            app.CallingApp.ConsultarButton.Enable = 'off';
            app.CallingApp.AnalizarpruebaButton.Enable = 'on';
            app.CallingApp.DesconectarButton.Enable = 'off';
        else
            app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
            app.CallingApp.ConsultarButton.Enable = 'on';
            app.CallingApp.AnalizarpruebaButton.Enable = 'on';
            app.CallingApp.DesconectarButton.Enable = 'on';
        end
            delete(app)
        end

        % Callback function
        function GENERARButtonPushed(app, event)

        end

        % Callback function
        function ClasificadorButtonPushed(app, event)
            clasificador(app);
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            canal = app.CanalSpinner.Value;
            muestras = app.EditField_2.Value;
            signal = cell2mat(app.datos);
            app.op = [0,0,0,0,0,0];
            app.indexinf = 256*(app.TiempoMinsEditField.Value);
            app.indexsup = 256*(app.TiempoMaxsEditField.Value);
            if app.indexinf==0
                app.indexinf=1;
            end
            if ((app.indexinf*256)/2)>0 || (( app.indexsup*256)/2)>0 %si es impar
                app.indexsup = round( app.indexsup);
                app.indexinf = round(app.indexinf);
            end
            if app.MediaButton.Value
                app.op(1)=1;
            elseif app.MAVButton.Value
                app.op(2)=1;     
            elseif app.CrucesporCeroButton.Value
                app.op(3)=1;
            elseif app.FrecuenciaMxButton.Value
                app.op(4)=1;
            elseif app.DesviacinestndarButton.Value
                app.op(5)=1;
            elseif app.CurtosisButton.Value
                app.op(6)=1;
            end
            [vector,~] = Features_tipicas(signal(canal,app.indexinf:app.indexsup),app.freq,1,muestras,canal,app.op);
            app.UIAxes_2.XLim=[1, length(vector)];
            plot(app.UIAxes_2,1:length(vector),vector)
       
        end

        % Button pushed function: SeleccionarEEGdeBasededatosButton
        function SeleccionarEEGdeBasededatosButtonPushed(app, event)
            app.PruebaApp = selectprueba(app, app.Conexion);
            app.ExtraerVectordeCaractersticasButton.Enable = 'on';
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            if (isempty(app.Conexion)||~isopen(app.Conexion))
            app.CallingApp.AgregarnuevopacienteButton.Enable = 'off';
            app.CallingApp.ConsultarButton.Enable = 'off';
            app.CallingApp.AnalizarpruebaButton.Enable = 'on';
            app.CallingApp.DesconectarButton.Enable = 'off';
        else
            app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
            app.CallingApp.ConsultarButton.Enable = 'on';
            app.CallingApp.AnalizarpruebaButton.Enable = 'on';
            app.CallingApp.DesconectarButton.Enable = 'on';
        end
            delete(app)
        end

        % Button pushed function: CagarArchivoMATButton
        function CagarArchivoMATButtonPushed(app, event)
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo M');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            workspace = load(strcat(path,file),'data');
            app.datos =  workspace.data;
            app.freq = 256;
            app.VisualizarButton.Enable = 'on';
            app.ExtraerVectordeCaractersticasButton.Enable ='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Carac = uifigure('Visible', 'off');
            app.Carac.Name = 'Visualización de Señal';
            app.Carac.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.Carac.CloseRequestFcn = createCallbackFcn(app, @CaracCloseRequest, true);
            app.UIAxes = uiaxes(app.Carac);
            title(app.UIAxes, 'Señal EEG')
            xlabel(app.UIAxes, 'Muestras ')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes_2 = uiaxes(app.Carac);
            xlabel(app.UIAxes_2, 'Ventanas')
            ylabel(app.UIAxes_2, 'Característica')
            app.CagarNuevoEEGButton = uibutton(app.Carac, 'push');
            app.CagarNuevoEEGButton.ButtonPushedFcn = createCallbackFcn(app, @CagarNuevoEEGButtonPushed, true);
            app.CagarNuevoEEGButton.Text = 'Cagar Nuevo EEG';
            app.CanalSpinnerLabel = uilabel(app.Carac);
            app.CanalSpinnerLabel.Text = 'Canal';
            app.CanalSpinner = uispinner(app.Carac);
            app.CanalSpinner.Limits = [1 23];
            app.CanalSpinner.Value = 1;
            app.VisualizarButton = uibutton(app.Carac, 'push');
            app.VisualizarButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarButtonPushed, true);
            app.VisualizarButton.Text = 'Visualizar';
            app.TiempoMinsEditFieldLabel = uilabel(app.Carac);
            app.TiempoMinsEditFieldLabel.Text = 'Tiempo Min (s)';
            app.TiempoMinsEditField = uieditfield(app.Carac, 'numeric');
            app.TiempoMaxsEditFieldLabel = uilabel(app.Carac);
            app.TiempoMaxsEditFieldLabel.Text = 'Tiempo Max (s)';
            app.TiempoMaxsEditField = uieditfield(app.Carac, 'numeric');
            app.TiempoMaxsEditField.Value = 10;
            app.ExtraerVectordeCaractersticasButton = uibutton(app.Carac, 'push');
            app.ExtraerVectordeCaractersticasButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraerVectordeCaractersticasButtonPushed, true);
            app.ExtraerVectordeCaractersticasButton.Text = 'Extraer Vector de Características';
            app.MuestrasporventanaLabel = uilabel(app.Carac);
            app.MuestrasporventanaLabel.Text = 'Muestras por ventana';
            app.CaractersticasTpicasButtonGroup = uibuttongroup(app.Carac);
            app.CaractersticasTpicasButtonGroup.Title = 'Características Típicas';
            app.MediaButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.MediaButton.Text = 'Media';
            app.MediaButton.Value = true;
            app.MAVButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.MAVButton.Text = 'MAV';
            app.CrucesporCeroButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.CrucesporCeroButton.Text = 'Cruces por Cero';
            app.FrecuenciaMxButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.FrecuenciaMxButton.Text = 'Frecuencia Máx.';
            app.DesviacinestndarButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.DesviacinestndarButton.Text = 'Desviación estándar';
            app.CurtosisButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.CurtosisButton.Text = 'Curtosis';
            app.CalcularButton = uibutton(app.Carac, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.sLabel = uilabel(app.Carac);
            app.sLabel.Text = '(256 = 1s)';
            app.EditField_2 = uieditfield(app.Carac, 'numeric');
            app.EditField_2.Value = 256;
            app.SeleccionarEEGdeBasededatosButton = uibutton(app.Carac, 'push');
            app.SeleccionarEEGdeBasededatosButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarEEGdeBasededatosButtonPushed, true);
            app.SeleccionarEEGdeBasededatosButton.Text = 'Seleccionar EEG de Base de datos';
            app.RegresarButton = uibutton(app.Carac, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.CagarArchivoMATButton = uibutton(app.Carac, 'push');
            app.CagarArchivoMATButton.ButtonPushedFcn = createCallbackFcn(app, @CagarArchivoMATButtonPushed, true);
            app.CagarArchivoMATButton.Text = 'Cagar Archivo MAT';
            % Show the figure after all components are created
            app.Carac.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = procesamiento(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Carac)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Carac)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Carac)
        end
    end
end